classdef Lab2_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        HomeTab                      matlab.ui.container.Tab
        TextArea_2                   matlab.ui.control.TextArea
        Image11                      matlab.ui.control.Image
        Image10                      matlab.ui.control.Image
        Image9                       matlab.ui.control.Image
        WhatsinstockButton           matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        StartheretogettheShelfElfbottoprepareyoureverydaymealLabel  matlab.ui.control.Label
        GETSTARTEDButton             matlab.ui.control.StateButton
        EstopSwitch                  matlab.ui.control.Switch
        EstopSwitchLabel             matlab.ui.control.Label
        SeeallMealsButton            matlab.ui.control.Button
        MyprofileButton              matlab.ui.control.Button
        Image13                      matlab.ui.control.Image
        OrderTab                     matlab.ui.container.Tab
        HomePageButton               matlab.ui.control.StateButton
        GalleryButton_2              matlab.ui.control.StateButton
        CategoriesPanel_2            matlab.ui.container.Panel
        ConfirmOrderButton           matlab.ui.control.StateButton
        Image18                      matlab.ui.control.Image
        Image17                      matlab.ui.control.Image
        Image16                      matlab.ui.control.Image
        MealsoftheDayListBox_2       matlab.ui.control.ListBox
        MealsoftheDayListBox_2Label  matlab.ui.control.Label
        ProfileInformationPanel      matlab.ui.container.Panel
        Image15                      matlab.ui.control.Image
        Image14                      matlab.ui.control.Image
        ListBox                      matlab.ui.control.ListBox
        OrderPerferencesPanel        matlab.ui.container.Panel
        SelectListBox                matlab.ui.control.ListBox
        SelectListBoxLabel           matlab.ui.control.Label
        GalleryTab                   matlab.ui.container.Tab
        WhatsinStockLabel            matlab.ui.control.Label
        DessertButton                matlab.ui.control.StateButton
        SnacksButton                 matlab.ui.control.StateButton
        DrinksButton                 matlab.ui.control.StateButton
        DinnerButton                 matlab.ui.control.StateButton
        LunchButton                  matlab.ui.control.StateButton
        BreakfastButton              matlab.ui.control.StateButton
        OrderPageButton              matlab.ui.control.StateButton
        SearchPageButton             matlab.ui.control.StateButton
        Image6                       matlab.ui.control.Image
        Image5                       matlab.ui.control.Image
        Image4                       matlab.ui.control.Image
        Image3                       matlab.ui.control.Image
        Image2                       matlab.ui.control.Image
        Image                        matlab.ui.control.Image
        SearchTab                    matlab.ui.container.Tab
        TextArea_3                   matlab.ui.control.TextArea
        Hyperlink3                   matlab.ui.control.Hyperlink
        Image19                      matlab.ui.control.Image
        GalleryButton                matlab.ui.control.StateButton
        TextArea                     matlab.ui.control.TextArea
        Hyperlink2                   matlab.ui.control.Hyperlink
        Image12                      matlab.ui.control.Image
        TeachFunctionTab             matlab.ui.container.Tab
        q4EditField                  matlab.ui.control.NumericEditField
        q4EditFieldLabel             matlab.ui.control.Label
        q3EditField                  matlab.ui.control.EditField
        q3EditFieldLabel             matlab.ui.control.Label
        SetqvaluesLabel              matlab.ui.control.Label
        q6EditFieldLabel             matlab.ui.control.Label
        q6EditField                  matlab.ui.control.NumericEditField
        q5EditFieldLabel             matlab.ui.control.Label
        q5EditField                  matlab.ui.control.NumericEditField
        q2EditFieldLabel             matlab.ui.control.Label
        q2EditField                  matlab.ui.control.NumericEditField
        q1EditField                  matlab.ui.control.NumericEditField
        q1EditFieldLabel             matlab.ui.control.Label
        q1Slider_6                   matlab.ui.control.Slider
        q1Slider_6Label              matlab.ui.control.Label
        q5Slider                     matlab.ui.control.Slider
        q5SliderLabel                matlab.ui.control.Label
        q6Slider                     matlab.ui.control.Slider
        q6SliderLabel                matlab.ui.control.Label
        q3Slider                     matlab.ui.control.Slider
        q3SliderLabel                matlab.ui.control.Label
        q4Slider                     matlab.ui.control.Slider
        q4SliderLabel                matlab.ui.control.Label
        q2Slider                     matlab.ui.control.Slider
        q2SliderLabel                matlab.ui.control.Label
        RoboButton                   matlab.ui.control.StateButton
        UR10Button                   matlab.ui.control.StateButton
        ZButton_2                    matlab.ui.control.StateButton
        ZButton                      matlab.ui.control.StateButton
        YButton_2                    matlab.ui.control.StateButton
        YButton                      matlab.ui.control.StateButton
        XButton_2                    matlab.ui.control.StateButton
        XButton                      matlab.ui.control.StateButton
        SwitchRobotsButton           matlab.ui.control.StateButton
    end

    properties (Access = private)
        modelRobo
        modelUR10
        robot
        qz = [0     0     0     0     0     0];
        qrRobo = [0	pi/2  -pi/2	0	0	0];
        qrUR10 = [pi/4	pi/2  -pi/2	0	0	0];
    end

    properties (Access = public)
        lab2Assignment %Instance for Lab2_Assignment class file
        EStopStatus  = 'Run' % Instance of Estop
        state
    end

    methods
        function self = Lab2GUI
            % Create an instance of Lab2_GUI
            self.lab2Assignment = Lab2_AssignmentUpdated();
        end
    end

    methods (Access = public)
        % Initialisation method
        function initialise(app, lab2Assignment)
            app.lab2Assignment = lab2Assignment;

            % Register the callback for the E-stop switch
            app.EstopSwitch.ValueChangedFcn = @(src, event)app.EstopSwitchValueChanged(src, event);
        end
        

        function startRobot(app)
            try
                % app.robot.start();
                app.EStopStatus = 'Run';
                disp('Robot started');
            catch exception
                % Handle any exceptions that may occur
                disp(['Error starting the robot: ' exception.message]);
            end
        end

        % Method to stop the robot
        function stopRobot(app)
            try
                % Code to stop the robot goes here
                % app.robot.stop();
                app.EStopStatus = 'Stop';
                disp('Robot stopped');
            catch exception
                % Handle any exceptions that may occur
                disp(['Error stopping the robot: ' exception.message]);
            end
        end

         function state = getSwitchStatus(app)
        % This function should return the current status of the E-stop switch.
        % The implementation will depend on how the E-stop switch's status is retrieved.
        state = app.EStopStatus; % Example implementation if status is stored in a property
         end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Code to run once GUI is loaded successfully
            ax = gca;

            % Make, save and plot some robots            
            app.modelRobo = Robo();
            app.modelRobo.model.base = transl([0.5,0,0]);
            app.modelRobo.model.animate(app.qrRobo);
            drawnow();        

            app.modelUR10 = UR10;
            app.modelUR10.model.base = transl([-0.5,0,0]);
            app.modelUR10.model.animate(app.qrUR10);
            drawnow();

            % Delete the green floor surface (need to count backwards
            % because once it is deleted the array is compressed)
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end
            app.XButton.Enable = false;
            app.XButton_2.Enable = false;
            app.YButton.Enable = false;
            app.YButton_2.Enable = false;
            app.ZButton.Enable = false;
            app.ZButton_2.Enable = false;
            app.q1Slider_6.Enable = false;
            app.q2Slider.Enable = false;
            app.q3Slider.Enable = false;
            app.q4Slider.Enable = false;
            app.q5Slider.Enable = false;
            app.q6Slider.Enable = false;

            workspace = [-1,1,-1,1,-1,1];
            axis(workspace)
        end

        % Button pushed function: MyprofileButton
        function MyprofileButtonPushed(app, event)
            % Switch to the "Order" tab
            app.TabGroup.SelectedTab = app.OrderTab;
        end

        % Button pushed function: WhatsinstockButton
        function WhatsinstockButtonPushed(app, event)
            % Switch to the "Order" tab
            app.TabGroup.SelectedTab = app.GalleryTab;
        end

        % Button pushed function: SeeallMealsButton
        function SeeallMealsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.OrderTab;
        end

        % Value changed function: GETSTARTEDButton
        function GETSTARTEDButtonValueChanged(app, event)
            app.TabGroup.SelectedTab = app.OrderTab;
        end

        % Value changed function: GalleryButton
        function GalleryButtonValueChanged(app, event)
            value = app.GalleryButton.Value;
            app.TabGroup.SelectedTab = app.GalleryTab;
        end

        % Value changed function: OrderPageButton
        function OrderPageButtonValueChanged(app, event)
            value = app.OrderPageButton.Value;
            app.TabGroup.SelectedTab = app.HomeTab;
        end

        % Value changed function: SearchPageButton
        function SearchPageButtonValueChanged(app, event)
            value = app.SearchPageButton.Value;
            app.TabGroup.SelectedTab = app.SearchTab;
        end

        % Value changed function: GalleryButton_2
        function GalleryButton_2ValueChanged(app, event)
            value = app.GalleryButton_2.Value;
            app.TabGroup.SelectedTab = app.GalleryTab;
        end

        % Value changed function: EstopSwitch
        function EstopSwitchValueChanged(app, event)
            value = app.EstopSwitch.Value;
            if strcmp(value, 'Run')
                % Code to start the robot goes here
                app.startRobot();
            elseif strcmp(value, 'Stop')
                % Code to stop the robot goes here
                app.stopRobot();
            else
                 disp('Invalid E-stop switch value');
            end
            
            app.lab2Assignment.toggleEstop(app.EstopStatus);  % Call the updated toggleEstop method

        end

        % Value changed function: HomePageButton
        function HomePageButtonValueChanged(app, event)
            value = app.HomePageButton.Value;
            app.TabGroup.SelectedTab = app.HomeTab;
        end

        % Value changed function: ConfirmOrderButton
        function ConfirmOrderButtonValueChanged(app, event)
            %value = app.ConfirmOrderButton.Value;
            app.Lab2_AssignmentAngie.objectLocater();  % Call the objectLocater method
        end

        % Value changed function: SwitchRobotsButton
        function SwitchRobotsButtonValueChanged(app, event)
            app.XButton.Enable = false;
            app.XButton_2.Enable = false;
            app.YButton.Enable = false;
            app.YButton_2.Enable = false;
            app.ZButton.Enable = false;
            app.ZButton_2.Enable = false;
            app.q1Slider_6.Enable = false;
            app.q2Slider.Enable = false;
            app.q3Slider.Enable = false;
            app.q4Slider.Enable = false;
            app.q5Slider.Enable = false;
            app.q6Slider.Enable = false;

            app.UR10Button.Enable = true;
            app.RoboButton.Enable = true;
        end

        % Value changed function: UR10Button
        function UR10ButtonValueChanged(app, event)
            app.robot = app.modelUR10;
            app.UR10Button.Enable = false;
            app.RoboButton.Enable = false;
            
            app.XButton.Enable = true;
            app.XButton_2.Enable = true;
            app.YButton.Enable = true;
            app.YButton_2.Enable = true;
            app.ZButton.Enable = true;
            app.ZButton_2.Enable = true;
            app.q1Slider_6.Enable = true;
            app.q2Slider.Enable = true;
            app.q3Slider.Enable = true;
            app.q4Slider.Enable = true;
            app.q5Slider.Enable = true;
            app.q6Slider.Enable = true;

        end

        % Value changed function: RoboButton
        function RoboButtonValueChanged(app, event)
            app.robot = app.modelRobo;
            app.UR10Button.Enable = false;
            app.RoboButton.Enable = false;
            
            app.XButton.Enable = true;
            app.XButton_2.Enable = true;
            app.YButton.Enable = true;
            app.YButton_2.Enable = true;
            app.ZButton.Enable = true;
            app.ZButton_2.Enable = true;
            app.q1Slider_6.Enable = true;
            app.q2Slider.Enable = true;
            app.q3Slider.Enable = true;
            app.q4Slider.Enable = true;
            app.q5Slider.Enable = true;
            app.q6Slider.Enable = true;
        end

        % Value changed function: XButton
        function XButtonValueChanged(app, event)
            step = 0.01;
            currentPos = app.robot.model.fkine(app.robot.model.getpos()).T;
            endPos = transl(currentPos(1,4) + step, currentPos(2,4), currentPos(3,4));

            resultQ = app.robot.model.ikcon(endPos, app.robot.model.getpos());

            app.robot.model.animate(resultQ);
            
        end

        % Value changed function: XButton_2
        function XButton_2ValueChanged(app, event)
            step = -0.01;
            currentPos = app.robot.model.fkine(app.robot.model.getpos()).T;
            endPos = transl(currentPos(1,4) + step, currentPos(2,4), currentPos(3,4));

            resultQ = app.robot.model.ikcon(endPos, app.robot.model.getpos());

            app.robot.model.animate(resultQ);
        end

        % Value changed function: YButton
        function YButtonValueChanged(app, event)
            step = 0.01;
            currentPos = app.robot.model.fkine(app.robot.model.getpos()).T;
            endPos = transl(currentPos(1,4), currentPos(2,4) + step, currentPos(3,4));

            resultQ = app.robot.model.ikcon(endPos, app.robot.model.getpos());

            app.robot.model.animate(resultQ);
        end

        % Value changed function: YButton_2
        function YButton_2ValueChanged(app, event)
            step = -0.01;
            currentPos = app.robot.model.fkine(app.robot.model.getpos()).T;
            endPos = transl(currentPos(1,4), currentPos(2,4) + step, currentPos(3,4));

            resultQ = app.robot.model.ikcon(endPos, app.robot.model.getpos());

            app.robot.model.animate(resultQ);
        end

        % Value changed function: ZButton
        function ZButtonValueChanged(app, event)
            step = 0.01;
            currentPos = app.robot.model.fkine(app.robot.model.getpos()).T;
            endPos = transl(currentPos(1,4), currentPos(2,4), currentPos(3,4) + step);

            resultQ = app.robot.model.ikcon(endPos, app.robot.model.getpos());

            app.robot.model.animate(resultQ);
        end

        % Value changed function: ZButton_2
        function ZButton_2ValueChanged(app, event)
            step = -0.01;
            currentPos = app.robot.model.fkine(app.robot.model.getpos()).T;
            endPos = transl(currentPos(1,4), currentPos(2,4), currentPos(3,4) + step);

            resultQ = app.robot.model.ikcon(endPos, app.robot.model.getpos());

            app.robot.model.animate(resultQ);
        end

        % Value changed function: q1EditField, q2EditField, q3EditField, 
        % ...and 3 other components
        function q1EditFieldValueChanged(app, event)
            q1 = app.q1EditField.Value*pi/180;
            q2 = app.q2EditField.Value*pi/180;
            q3 = app.q3EditField.Value*pi/180;
            q4 = app.q4EditField.Value*pi/180;
            q5 = app.q5EditField.Value*pi/180;
            q6 = app.q6EditField.Value*pi/180;
            newQ = [q1,q2,q3,q4,q5,q6];
            app.robot.model.animate(newQ);
            
        end

        % Value changed function: q1Slider_6
        function q1Slider_6ValueChanged(app, event)
            changingValue = event.Value;
            currentQ = app.robot.model.getpos();
            currentQ(1) = changingValue*pi/180;
            app.robot.model.animate(currentQ);
        end

        % Value changed function: q2Slider
        function q2SliderValueChanged(app, event)
            changingValue = event.Value;
            currentQ = app.robot.model.getpos();
            currentQ(2) = changingValue*pi/180;
            app.robot.model.animate(currentQ);
        end

        % Value changed function: q3Slider
        function q3SliderValueChanged(app, event)
            changingValue = event.Value;
            currentQ = app.robot.model.getpos();
            currentQ(3) = changingValue*pi/180;
            app.robot.model.animate(currentQ);
        end

        % Value changed function: q4Slider
        function q4SliderValueChanged(app, event)
            changingValue = event.Value;
            currentQ = app.robot.model.getpos();
            currentQ(4) = changingValue*pi/180;
            app.robot.model.animate(currentQ);
        end

        % Value changed function: q5Slider
        function q5SliderValueChanged(app, event)
            changingValue = event.Value;
            currentQ = app.robot.model.getpos();
            currentQ(5) = changingValue*pi/180;
            app.robot.model.animate(currentQ);
        end

        % Value changed function: q6Slider
        function q6SliderValueChanged(app, event)
            changingValue = event.Value;
            currentQ = app.robot.model.getpos();
            currentQ(6) = changingValue*pi/180;
            app.robot.model.animate(currentQ);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.Image13 = uiimage(app.HomeTab);
            app.Image13.ImageSource = 'Green Aesthetic Computer Template _ Powerpoint slide designs, Powerpoint background templates, Powerpoint background design.jpeg';
            app.MyprofileButton = uibutton(app.HomeTab, 'push');
            app.MyprofileButton.ButtonPushedFcn = createCallbackFcn(app, @MyprofileButtonPushed, true);
            app.MyprofileButton.Text = 'My profile';
            app.SeeallMealsButton = uibutton(app.HomeTab, 'push');
            app.SeeallMealsButton.ButtonPushedFcn = createCallbackFcn(app, @SeeallMealsButtonPushed, true);
            app.SeeallMealsButton.Text = 'See all Meals';
            app.EstopSwitchLabel = uilabel(app.HomeTab);
            app.EstopSwitchLabel.Text = 'E-stop';
            app.EstopSwitch = uiswitch(app.HomeTab, 'slider');
            app.EstopSwitch.Items = {'Run', 'Stop'};
            app.EstopSwitch.ValueChangedFcn = createCallbackFcn(app, @EstopSwitchValueChanged, true);
            app.EstopSwitch.Value = 'Run';
            app.GETSTARTEDButton = uibutton(app.HomeTab, 'state');
            app.GETSTARTEDButton.ValueChangedFcn = createCallbackFcn(app, @GETSTARTEDButtonValueChanged, true);
            app.GETSTARTEDButton.Text = 'GET STARTED';
            app.StartheretogettheShelfElfbottoprepareyoureverydaymealLabel = uilabel(app.HomeTab);
            app.StartheretogettheShelfElfbottoprepareyoureverydaymealLabel.Text = 'Begin your culinary journey with ShelfElf, your personalised meal preparation bot';
            app.Label = uilabel(app.HomeTab);
            app.Label.Text = 'Let ShelfElf prep your meals for the day';
            app.WhatsinstockButton = uibutton(app.HomeTab, 'push');
            app.WhatsinstockButton.ButtonPushedFcn = createCallbackFcn(app, @WhatsinstockButtonPushed, true);
            app.WhatsinstockButton.Text = 'What''s in stock';
            app.Image9 = uiimage(app.HomeTab);
            app.Image9.ImageSource = 'Icon store matcha.jpeg';
            app.Image10 = uiimage(app.HomeTab);
            app.Image10.ImageSource = 'Sage Green Cat icon.jpeg';
            app.Image11 = uiimage(app.HomeTab);
            app.Image11.ImageSource = 'Jessica on Snapchat.jpeg';
            app.TextArea_2 = uitextarea(app.HomeTab);
            app.TextArea_2.Value = {'Functional Emergency Stop for Robot Operation'};
            app.OrderTab = uitab(app.TabGroup);
            app.OrderTab.Title = 'Order';
            app.ProfileInformationPanel = uipanel(app.OrderTab);
            app.ProfileInformationPanel.Title = 'Profile Information';
            app.OrderPerferencesPanel = uipanel(app.ProfileInformationPanel);
            app.OrderPerferencesPanel.Title = 'Order Perferences';
            app.SelectListBoxLabel = uilabel(app.OrderPerferencesPanel);
            app.SelectListBoxLabel.Text = 'Select';
            app.SelectListBox = uilistbox(app.OrderPerferencesPanel);
            app.SelectListBox.Items = {'Order history', 'Favourites', 'Recommendations'};
            app.SelectListBox.Value = 'Order history';
            app.ListBox = uilistbox(app.ProfileInformationPanel);
            app.ListBox.Items = {'Account Info', 'Delivery Address', 'Inbox'};
            app.ListBox.Value = 'Account Info';
            app.Image14 = uiimage(app.ProfileInformationPanel);
            app.Image14.ImageSource = 'افـتـارات وه_ـمـية _.jpeg';
            app.Image15 = uiimage(app.ProfileInformationPanel);
            app.Image15.ImageSource = 'icon.jpeg';
            app.CategoriesPanel_2 = uipanel(app.OrderTab);
            app.CategoriesPanel_2.Title = 'Categories';
            app.MealsoftheDayListBox_2Label = uilabel(app.CategoriesPanel_2);
            app.MealsoftheDayListBox_2Label.Text = 'Meals of the Day';
            app.MealsoftheDayListBox_2 = uilistbox(app.CategoriesPanel_2);
            app.MealsoftheDayListBox_2.Items = {'Breakfast', 'Lunch', 'Dinner', 'Drinks', 'Snack', 'Dessert'};
            app.MealsoftheDayListBox_2.Value = 'Breakfast';
            app.Image16 = uiimage(app.CategoriesPanel_2);
            app.Image16.ImageSource = 'Ｙｏｏｎｉｅ.jpeg';
            app.Image17 = uiimage(app.CategoriesPanel_2);
            app.Image17.ImageSource = 'icon8.png';
            app.Image18 = uiimage(app.CategoriesPanel_2);
            app.Image18.ImageSource = 'icon7.png';
            app.ConfirmOrderButton = uibutton(app.CategoriesPanel_2, 'state');
            app.ConfirmOrderButton.ValueChangedFcn = createCallbackFcn(app, @ConfirmOrderButtonValueChanged, true);
            app.ConfirmOrderButton.Text = 'Confirm Order';
            app.GalleryButton_2 = uibutton(app.OrderTab, 'state');
            app.GalleryButton_2.ValueChangedFcn = createCallbackFcn(app, @GalleryButton_2ValueChanged, true);
            app.GalleryButton_2.Text = 'Gallery ->';
            app.HomePageButton = uibutton(app.OrderTab, 'state');
            app.HomePageButton.ValueChangedFcn = createCallbackFcn(app, @HomePageButtonValueChanged, true);
            app.HomePageButton.Text = '<- Home Page';
            app.GalleryTab = uitab(app.TabGroup);
            app.GalleryTab.Title = 'Gallery';
            app.Image = uiimage(app.GalleryTab);
            app.Image.ImageSource = 'icon1.png';
            app.Image2 = uiimage(app.GalleryTab);
            app.Image2.ImageSource = 'icon2.png';
            app.Image3 = uiimage(app.GalleryTab);
            app.Image3.ImageSource = 'icon3.png';
            app.Image4 = uiimage(app.GalleryTab);
            app.Image4.ImageSource = 'icon4.png';
            app.Image5 = uiimage(app.GalleryTab);
            app.Image5.ImageSource = 'icon5.png';
            app.Image6 = uiimage(app.GalleryTab);
            app.Image6.ImageSource = 'icon6.png';
            app.SearchPageButton = uibutton(app.GalleryTab, 'state');
            app.SearchPageButton.ValueChangedFcn = createCallbackFcn(app, @SearchPageButtonValueChanged, true);
            app.SearchPageButton.Text = 'Search Page ->';
            app.OrderPageButton = uibutton(app.GalleryTab, 'state');
            app.OrderPageButton.ValueChangedFcn = createCallbackFcn(app, @OrderPageButtonValueChanged, true);
            app.OrderPageButton.Text = '<- Order Page';
            app.BreakfastButton = uibutton(app.GalleryTab, 'state');
            app.BreakfastButton.Text = 'Breakfast';
            app.LunchButton = uibutton(app.GalleryTab, 'state');
            app.LunchButton.Text = 'Lunch';
            app.DinnerButton = uibutton(app.GalleryTab, 'state');
            app.DinnerButton.Text = 'Dinner';
            app.DrinksButton = uibutton(app.GalleryTab, 'state');
            app.DrinksButton.Text = 'Drinks';
            app.SnacksButton = uibutton(app.GalleryTab, 'state');
            app.SnacksButton.Text = 'Snacks';
            app.DessertButton = uibutton(app.GalleryTab, 'state');
            app.DessertButton.Text = 'Dessert';
            app.WhatsinStockLabel = uilabel(app.GalleryTab);
            app.WhatsinStockLabel.Text = 'What''s in Stock';
            app.SearchTab = uitab(app.TabGroup);
            app.SearchTab.Title = 'Search';
            app.Image12 = uiimage(app.SearchTab);
            app.Image12.ImageSource = 'Google search templete cute 💗.jpeg';
            app.Hyperlink2 = uihyperlink(app.SearchTab);
            app.Hyperlink2.URL = 'https://www.woolworths.com.au/?utm_source=google&utm_medium=cpc&utm_campaign=WW-0001&cq_net=g&cq_src=GOOGLE&cq_cmp=Woolies_8458_BAU_Brand_Exact_Head%20Term%20Pure_WW-0001&cq_med=71700000071994282&cq_plac=&cq_term=woolworths&ds_adt=&cq_plt=gp&cq_gclid=CjwKCAjw7c2pBhAZEiwA88pOF9TqWBwI3q790H22A4HGZWsAoFJjKowIudiV3FWZ1Op-FpNmKClg7BoCyTQQAvD_BwE&ds_de=c&ds_pc=&ds_cr=528130855897&ds_tid=aud-848956741194:kwd-14367402&ds_locphys=9071713&ds_pid=&cmpid=smsm:ds:GOOGLE:Woolies_8458_BAU_Brand_Exact_Head%20Term%20Pure_WW-0001:woolworths&gclid=CjwKCAjw7c2pBhAZEiwA88pOF9TqWBwI3q790H22A4HGZWsAoFJjKowIudiV3FWZ1Op-FpNmKClg7BoCyTQQAvD_BwE&gclsrc=aw.ds';
            app.Hyperlink2.Text = 'Supermarket';
            app.TextArea = uitextarea(app.SearchTab);
            app.TextArea.Value = {'Place an order for additonal groceries from your nearby supermarket'};
            app.GalleryButton = uibutton(app.SearchTab, 'state');
            app.GalleryButton.ValueChangedFcn = createCallbackFcn(app, @GalleryButtonValueChanged, true);
            app.GalleryButton.Text = '<- Gallery';
            app.Image19 = uiimage(app.SearchTab);
            app.Image19.ImageSource = 'Screen Shot 2023-10-23 at 9.45.47 pm.png';
            app.Hyperlink3 = uihyperlink(app.SearchTab);
            app.Hyperlink3.URL = 'https://online-barcode-reader.inliteresearch.com/';
            app.Hyperlink3.Text = 'Bar Code Scanner';
            app.TextArea_3 = uitextarea(app.SearchTab);
            app.TextArea_3.Value = {'Click here to scan your products'};
            app.TeachFunctionTab = uitab(app.TabGroup);
            app.TeachFunctionTab.Title = 'Teach Function';
            app.SwitchRobotsButton = uibutton(app.TeachFunctionTab, 'state');
            app.SwitchRobotsButton.ValueChangedFcn = createCallbackFcn(app, @SwitchRobotsButtonValueChanged, true);
            app.SwitchRobotsButton.Text = 'Switch Robots';
            app.XButton = uibutton(app.TeachFunctionTab, 'state');
            app.XButton.ValueChangedFcn = createCallbackFcn(app, @XButtonValueChanged, true);
            app.XButton.Text = '+X';
            app.XButton_2 = uibutton(app.TeachFunctionTab, 'state');
            app.XButton_2.ValueChangedFcn = createCallbackFcn(app, @XButton_2ValueChanged, true);
            app.XButton_2.Text = '-X';
            app.YButton = uibutton(app.TeachFunctionTab, 'state');
            app.YButton.ValueChangedFcn = createCallbackFcn(app, @YButtonValueChanged, true);
            app.YButton.Text = '+Y';
            app.YButton_2 = uibutton(app.TeachFunctionTab, 'state');
            app.YButton_2.ValueChangedFcn = createCallbackFcn(app, @YButton_2ValueChanged, true);
            app.YButton_2.Text = '-Y';
            app.ZButton = uibutton(app.TeachFunctionTab, 'state');
            app.ZButton.ValueChangedFcn = createCallbackFcn(app, @ZButtonValueChanged, true);
            app.ZButton.Text = '+Z';
            app.ZButton_2 = uibutton(app.TeachFunctionTab, 'state');
            app.ZButton_2.ValueChangedFcn = createCallbackFcn(app, @ZButton_2ValueChanged, true);
            app.ZButton_2.Text = '-Z';
            app.UR10Button = uibutton(app.TeachFunctionTab, 'state');
            app.UR10Button.ValueChangedFcn = createCallbackFcn(app, @UR10ButtonValueChanged, true);
            app.UR10Button.Text = 'UR10';
            app.RoboButton = uibutton(app.TeachFunctionTab, 'state');
            app.RoboButton.ValueChangedFcn = createCallbackFcn(app, @RoboButtonValueChanged, true);
            app.RoboButton.Text = 'Robo';
            app.q2SliderLabel = uilabel(app.TeachFunctionTab);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.TeachFunctionTab);
            app.q2Slider.Limits = [-180 180];
            app.q2Slider.MajorTicks = [-180 -150 -120 -90 -60 -30 0 30 60 90 120 150 180];
            app.q2Slider.ValueChangedFcn = createCallbackFcn(app, @q2SliderValueChanged, true);
            app.q4SliderLabel = uilabel(app.TeachFunctionTab);
            app.q4SliderLabel.Text = 'q4';
            app.q4Slider = uislider(app.TeachFunctionTab);
            app.q4Slider.Limits = [-180 180];
            app.q4Slider.MajorTicks = [-180 -150 -120 -90 -60 -30 0 30 60 90 120 150 180];
            app.q4Slider.ValueChangedFcn = createCallbackFcn(app, @q4SliderValueChanged, true);
            app.q3SliderLabel = uilabel(app.TeachFunctionTab);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.TeachFunctionTab);
            app.q3Slider.Limits = [-180 180];
            app.q3Slider.MajorTicks = [-180 -150 -120 -90 -60 -30 0 30 60 90 120 150 180];
            app.q3Slider.ValueChangedFcn = createCallbackFcn(app, @q3SliderValueChanged, true);
            app.q6SliderLabel = uilabel(app.TeachFunctionTab);
            app.q6SliderLabel.Text = 'q6';
            app.q6Slider = uislider(app.TeachFunctionTab);
            app.q6Slider.Limits = [-180 180];
            app.q6Slider.MajorTicks = [-180 -150 -120 -90 -60 -30 0 30 60 90 120 150 180];
            app.q6Slider.ValueChangedFcn = createCallbackFcn(app, @q6SliderValueChanged, true);
            app.q5SliderLabel = uilabel(app.TeachFunctionTab);
            app.q5SliderLabel.Text = 'q5';
            app.q5Slider = uislider(app.TeachFunctionTab);
            app.q5Slider.Limits = [-180 180];
            app.q5Slider.MajorTicks = [-180 -150 -120 -90 -60 -30 0 30 60 90 120 150 180];
            app.q5Slider.ValueChangedFcn = createCallbackFcn(app, @q5SliderValueChanged, true);
            app.q1Slider_6Label = uilabel(app.TeachFunctionTab);
            app.q1Slider_6Label.Text = 'q1';
            app.q1Slider_6 = uislider(app.TeachFunctionTab);
            app.q1Slider_6.Limits = [-180 180];
            app.q1Slider_6.MajorTicks = [-180 -150 -120 -90 -60 -30 0 30 60 90 120 150 180];
            app.q1Slider_6.ValueChangedFcn = createCallbackFcn(app, @q1Slider_6ValueChanged, true);
            app.q1EditFieldLabel = uilabel(app.TeachFunctionTab);
            app.q1EditFieldLabel.Text = 'q1';
            app.q1EditField = uieditfield(app.TeachFunctionTab, 'numeric');
            app.q1EditField.ValueChangedFcn = createCallbackFcn(app, @q1EditFieldValueChanged, true);
            app.q2EditField = uieditfield(app.TeachFunctionTab, 'numeric');
            app.q2EditField.ValueChangedFcn = createCallbackFcn(app, @q1EditFieldValueChanged, true);
            app.q2EditFieldLabel = uilabel(app.TeachFunctionTab);
            app.q2EditFieldLabel.Text = 'q2';
            app.q5EditField = uieditfield(app.TeachFunctionTab, 'numeric');
            app.q5EditField.ValueChangedFcn = createCallbackFcn(app, @q1EditFieldValueChanged, true);
            app.q5EditFieldLabel = uilabel(app.TeachFunctionTab);
            app.q5EditFieldLabel.Text = 'q5';
            app.q6EditField = uieditfield(app.TeachFunctionTab, 'numeric');
            app.q6EditField.ValueChangedFcn = createCallbackFcn(app, @q1EditFieldValueChanged, true);
            app.q6EditFieldLabel = uilabel(app.TeachFunctionTab);
            app.q6EditFieldLabel.Text = 'q6';
            app.SetqvaluesLabel = uilabel(app.TeachFunctionTab);
            app.SetqvaluesLabel.Text = 'Set q values';
            app.q3EditFieldLabel = uilabel(app.TeachFunctionTab);
            app.q3EditFieldLabel.Text = 'q3';
            app.q3EditField = uieditfield(app.TeachFunctionTab, 'text');
            app.q3EditField.ValueChangedFcn = createCallbackFcn(app, @q1EditFieldValueChanged, true);
            app.q3EditField.Value = '                         0';
            app.q4EditFieldLabel = uilabel(app.TeachFunctionTab);
            app.q4EditFieldLabel.Text = 'q4';
            app.q4EditField = uieditfield(app.TeachFunctionTab, 'numeric');
            app.q4EditField.ValueChangedFcn = createCallbackFcn(app, @q1EditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab2_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end