classdef teachFunction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        xButton_2        matlab.ui.control.StateButton
        xButton          matlab.ui.control.StateButton
        PlotrobotButton  matlab.ui.control.StateButton
        UIAxes           matlab.ui.control.UIAxes
    end

    
    methods (Access = private)

        % Helper function to move the robot in the x-direction
        function moveRobotInXDirection(app, distance)
            % Check if the robot is already plotted
            if isfield(app, 'roboRobot')
                roboRobot = app.roboRobot;
                % Get the current robot position
                currentQ = roboRobot.model.getpos;
        
                % Calculate the new position by adding or subtracting the specified distance
                newQ = currentQ;
                newQ(1) = newQ(1) + distance;
        
                % Animate the robot to the new position
                roboRobot.model.animate(newQ);
            end

        end
  end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PlotrobotButton
        function PlotrobotButtonValueChanged(app, event)
            value = app.PlotrobotButton.Value;
            if value
                % Button is pressed, so create and plot the Robo robot
                baseTr = transl(0, 0, 0);  % You can adjust the base transformation as needed
                roboRobot = Robo(baseTr);

                % Plot the robot in the UIAxes
                axes(app.UIAxes);
                roboRobot.model.plot3d(zeros(1, roboRobot.model.n), 'noarrow');

                % Set appropriate axes properties if needed
                view(3);  % Set the view to 3D
                axis equal;  % Ensure equal aspect ratio
                grid on;  % Show grid
            else
                % Button is released, clear the plot
                %cla(app.UIAxes);
            end
            
        end

        % Value changed function: xButton
        function xButtonValueChanged(app, event)
            value = app.xButton.Value;
            moveRobotInXDirection(app, +0.01);
            
        end

        % Value changed function: xButton_2
        function xButton_2ValueChanged(app, event)
            value = app.xButton_2.Value;
            moveRobotInXDirection(app, -0.01);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PlotrobotButton = uibutton(app.UIFigure, 'state');
            app.PlotrobotButton.ValueChangedFcn = createCallbackFcn(app, @PlotrobotButtonValueChanged, true);
            app.PlotrobotButton.Text = 'Plot robot';
            app.xButton = uibutton(app.UIFigure, 'state');
            app.xButton.ValueChangedFcn = createCallbackFcn(app, @xButtonValueChanged, true);
            app.xButton.Text = 'x+';
            app.xButton_2 = uibutton(app.UIFigure, 'state');
            app.xButton_2.ValueChangedFcn = createCallbackFcn(app, @xButton_2ValueChanged, true);
            app.xButton_2.Text = 'x-';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = teachFunction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end