classdef teachFunctionDidi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        q6Slider              matlab.ui.control.Slider
        q6SliderLabel         matlab.ui.control.Label
        q5Slider              matlab.ui.control.Slider
        q5SliderLabel         matlab.ui.control.Label
        q4Slider              matlab.ui.control.Slider
        q4SliderLabel         matlab.ui.control.Label
        q3Slider              matlab.ui.control.Slider
        q3SliderLabel         matlab.ui.control.Label
        q2Slider              matlab.ui.control.Slider
        q2SliderLabel         matlab.ui.control.Label
        q1Slider              matlab.ui.control.Slider
        q1SliderLabel         matlab.ui.control.Label
        SwitchRobotsButton    matlab.ui.control.Button
        q6EditField           matlab.ui.control.NumericEditField
        q6EditFieldLabel      matlab.ui.control.Label
        q5EditField           matlab.ui.control.NumericEditField
        q5EditFieldLabel      matlab.ui.control.Label
        q4EditField           matlab.ui.control.NumericEditField
        q4EditFieldLabel      matlab.ui.control.Label
        q3EditField           matlab.ui.control.NumericEditField
        q3EditFieldLabel      matlab.ui.control.Label
        q1EditField           matlab.ui.control.NumericEditField
        q1Label               matlab.ui.control.Label
        SetJointsAnglesLabel  matlab.ui.control.Label
        RoboButton            matlab.ui.control.Button
        UR10Button            matlab.ui.control.Button
        q2EditField           matlab.ui.control.NumericEditField
        q2EditFieldLabel      matlab.ui.control.Label
        ZButton_2             matlab.ui.control.Button
        ZButton               matlab.ui.control.Button
        YButton_2             matlab.ui.control.Button
        YButton               matlab.ui.control.Button
        XButton_2             matlab.ui.control.Button
        XButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        modelRobo
        modelUR10
        robot
        qz = [0     0     0     0     0     0];
        qrRobo = [0	pi/2  -pi/2	0	0	0];
        qrUR10 = [pi/4	pi/2  -pi/2	0	0	0];
    end
    
    % Initialise App Screen with Robots

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Code to run once GUI is loaded successfully
            ax = gca;

            % Make, save and plot some robots            
            app.modelRobo = Robo();
            app.modelRobo.model.base = transl([0.5,0,0]);
            app.modelRobo.model.animate(app.qrRobo);
            drawnow();        

            app.modelUR10 = UR10;
            app.modelUR10.model.base = transl([-0.5,0,0]);
            app.modelUR10.model.animate(app.qrUR10);
            drawnow();

            % Delete the green floor surface (need to count backwards
            % because once it is deleted the array is compressed)
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end
            app.XButton.Enable = false;
            app.XButton_2.Enable = false;
            app.YButton.Enable = false;
            app.YButton_2.Enable = false;
            app.ZButton.Enable = false;
            app.ZButton_2.Enable = false;
            app.q1Slider.Enable = false;
            app.q2Slider.Enable = false;
            app.q3Slider.Enable = false;
            app.q4Slider.Enable = false;
            app.q5Slider.Enable = false;
            app.q6Slider.Enable = false;

            workspace = [-1,1,-1,1,-1,1];
            axis(workspace)


        end

        % Button pushed function: UR10Button
        function UR10ButtonPushed(app, event)
            app.robot = app.modelUR10;
            app.UR10Button.Enable = false;
            app.RoboButton.Enable = false;
            
            app.XButton.Enable = true;
            app.XButton_2.Enable = true;
            app.YButton.Enable = true;
            app.YButton_2.Enable = true;
            app.ZButton.Enable = true;
            app.ZButton_2.Enable = true;
            app.q1Slider.Enable = true;
            app.q2Slider.Enable = true;
            app.q3Slider.Enable = true;
            app.q4Slider.Enable = true;
            app.q5Slider.Enable = true;
            app.q6Slider.Enable = true;
        end

        % Button pushed function: RoboButton
        function RoboButtonPushed(app, event)
            app.robot = app.modelRobo;
            app.UR10Button.Enable = false;
            app.RoboButton.Enable = false;
            
            app.XButton.Enable = true;
            app.XButton_2.Enable = true;
            app.YButton.Enable = true;
            app.YButton_2.Enable = true;
            app.ZButton.Enable = true;
            app.ZButton_2.Enable = true;
            app.q1Slider.Enable = true;
            app.q2Slider.Enable = true;
            app.q3Slider.Enable = true;
            app.q4Slider.Enable = true;
            app.q5Slider.Enable = true;
            app.q6Slider.Enable = true;
            
        end

        % Button pushed function: SwitchRobotsButton
        function SwitchRobotsButtonPushed(app, event)
            app.XButton.Enable = false;
            app.XButton_2.Enable = false;
            app.YButton.Enable = false;
            app.YButton_2.Enable = false;
            app.ZButton.Enable = false;
            app.ZButton_2.Enable = false;
            app.q1Slider.Enable = false;
            app.q2Slider.Enable = false;
            app.q3Slider.Enable = false;
            app.q4Slider.Enable = false;
            app.q5Slider.Enable = false;
            app.q6Slider.Enable = false;

            app.UR10Button.Enable = true;
            app.RoboButton.Enable = true;
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            step = 0.01;
            currentPos = app.robot.model.fkine(app.robot.model.getpos()).T;
            endPos = transl(currentPos(1,4) + step, currentPos(2,4), currentPos(3,4));

            resultQ = app.robot.model.ikcon(endPos, app.robot.model.getpos());

            app.robot.model.animate(resultQ);
        end

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
            step = -0.01;
            currentPos = app.robot.model.fkine(app.robot.model.getpos()).T;
            endPos = transl(currentPos(1,4) + step, currentPos(2,4), currentPos(3,4));

            resultQ = app.robot.model.ikcon(endPos, app.robot.model.getpos());

            app.robot.model.animate(resultQ);
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            step = 0.01;
            currentPos = app.robot.model.fkine(app.robot.model.getpos()).T;
            endPos = transl(currentPos(1,4), currentPos(2,4) + step, currentPos(3,4));

            resultQ = app.robot.model.ikcon(endPos, app.robot.model.getpos());

            app.robot.model.animate(resultQ);
        end

        % Button pushed function: YButton_2
        function YButton_2Pushed(app, event)
            step = -0.01;
            currentPos = app.robot.model.fkine(app.robot.model.getpos()).T;
            endPos = transl(currentPos(1,4), currentPos(2,4) + step, currentPos(3,4));

            resultQ = app.robot.model.ikcon(endPos, app.robot.model.getpos());

            app.robot.model.animate(resultQ);
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
            step = 0.01;
            currentPos = app.robot.model.fkine(app.robot.model.getpos()).T;
            endPos = transl(currentPos(1,4), currentPos(2,4), currentPos(3,4) + step);

            resultQ = app.robot.model.ikcon(endPos, app.robot.model.getpos());

            app.robot.model.animate(resultQ);
        end

        % Button pushed function: ZButton_2
        function ZButton_2Pushed(app, event)
            step = -0.01;
            currentPos = app.robot.model.fkine(app.robot.model.getpos()).T;
            endPos = transl(currentPos(1,4), currentPos(2,4), currentPos(3,4) + step);

            resultQ = app.robot.model.ikcon(endPos, app.robot.model.getpos());

            app.robot.model.animate(resultQ);
        end

        % Value changed function: q1EditField, q2EditField, q3EditField, 
        % ...and 3 other components
        function q6EditFieldValueChanged(app, event)
            q1 = app.q1EditField.Value*pi/180;
            q2 = app.q2EditField.Value*pi/180;
            q3 = app.q3EditField.Value*pi/180;
            q4 = app.q4EditField.Value*pi/180;
            q5 = app.q5EditField.Value*pi/180;
            q6 = app.q6EditField.Value*pi/180;
            newQ = [q1,q2,q3,q4,q5,q6];
            app.robot.model.animate(newQ);
        end

        % Value changing function: q1Slider
        function q1SliderValueChanging(app, event)
            changingValue = event.Value;
            currentQ = app.robot.model.getpos();
            currentQ(1) = changingValue*pi/180;
            app.robot.model.animate(currentQ);
        end

        % Value changing function: q2Slider
        function q2SliderValueChanging(app, event)
            changingValue = event.Value;
            currentQ = app.robot.model.getpos();
            currentQ(2) = changingValue*pi/180;
            app.robot.model.animate(currentQ);
        end

        % Value changing function: q3Slider
        function q3SliderValueChanging(app, event)
            changingValue = event.Value;
            currentQ = app.robot.model.getpos();
            currentQ(3) = changingValue*pi/180;
            app.robot.model.animate(currentQ);
        end

        % Value changing function: q4Slider
        function q4SliderValueChanging(app, event)
            changingValue = event.Value;
            currentQ = app.robot.model.getpos();
            currentQ(4) = changingValue*pi/180;
            app.robot.model.animate(currentQ);
        end

        % Value changing function: q5Slider
        function q5SliderValueChanging(app, event)
            changingValue = event.Value;
            currentQ = app.robot.model.getpos();
            currentQ(5) = changingValue*pi/180;
            app.robot.model.animate(currentQ);
        end

        % Value changing function: q6Slider
        function q6SliderValueChanging(app, event)
            changingValue = event.Value;
            currentQ = app.robot.model.getpos();
            currentQ(6) = changingValue*pi/180;
            app.robot.model.animate(currentQ);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.XButton = uibutton(app.UIFigure, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = '+X';
            app.XButton_2 = uibutton(app.UIFigure, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Text = '-X';
            app.YButton = uibutton(app.UIFigure, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = '+Y';
            app.YButton_2 = uibutton(app.UIFigure, 'push');
            app.YButton_2.ButtonPushedFcn = createCallbackFcn(app, @YButton_2Pushed, true);
            app.YButton_2.Text = '-Y';
            app.ZButton = uibutton(app.UIFigure, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Text = '+Z';
            app.ZButton_2 = uibutton(app.UIFigure, 'push');
            app.ZButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZButton_2Pushed, true);
            app.ZButton_2.Text = '-Z';
            app.q2EditFieldLabel = uilabel(app.UIFigure);
            app.q2EditFieldLabel.Text = 'q2';
            app.q2EditField = uieditfield(app.UIFigure, 'numeric');
            app.q2EditField.ValueChangedFcn = createCallbackFcn(app, @q6EditFieldValueChanged, true);
            app.UR10Button = uibutton(app.UIFigure, 'push');
            app.UR10Button.ButtonPushedFcn = createCallbackFcn(app, @UR10ButtonPushed, true);
            app.UR10Button.Text = 'UR10';
            app.RoboButton = uibutton(app.UIFigure, 'push');
            app.RoboButton.ButtonPushedFcn = createCallbackFcn(app, @RoboButtonPushed, true);
            app.RoboButton.Text = 'Robo';
            app.SetJointsAnglesLabel = uilabel(app.UIFigure);
            app.SetJointsAnglesLabel.Text = 'Set Joints Angles';
            app.q1Label = uilabel(app.UIFigure);
            app.q1Label.Text = 'q1';
            app.q1EditField = uieditfield(app.UIFigure, 'numeric');
            app.q1EditField.ValueChangedFcn = createCallbackFcn(app, @q6EditFieldValueChanged, true);
            app.q3EditFieldLabel = uilabel(app.UIFigure);
            app.q3EditFieldLabel.Text = 'q3';
            app.q3EditField = uieditfield(app.UIFigure, 'numeric');
            app.q3EditField.ValueChangedFcn = createCallbackFcn(app, @q6EditFieldValueChanged, true);
            app.q4EditFieldLabel = uilabel(app.UIFigure);
            app.q4EditFieldLabel.Text = 'q4';
            app.q4EditField = uieditfield(app.UIFigure, 'numeric');
            app.q4EditField.ValueChangedFcn = createCallbackFcn(app, @q6EditFieldValueChanged, true);
            app.q5EditFieldLabel = uilabel(app.UIFigure);
            app.q5EditFieldLabel.Text = 'q5';
            app.q5EditField = uieditfield(app.UIFigure, 'numeric');
            app.q5EditField.ValueChangedFcn = createCallbackFcn(app, @q6EditFieldValueChanged, true);
            app.q6EditFieldLabel = uilabel(app.UIFigure);
            app.q6EditFieldLabel.Text = 'q6';
            app.q6EditField = uieditfield(app.UIFigure, 'numeric');
            app.q6EditField.ValueChangedFcn = createCallbackFcn(app, @q6EditFieldValueChanged, true);
            app.SwitchRobotsButton = uibutton(app.UIFigure, 'push');
            app.SwitchRobotsButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchRobotsButtonPushed, true);
            app.SwitchRobotsButton.Text = 'Switch Robots';
            app.q1SliderLabel = uilabel(app.UIFigure);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.UIFigure);
            app.q1Slider.Limits = [-180 180];
            app.q1Slider.ValueChangingFcn = createCallbackFcn(app, @q1SliderValueChanging, true);
            app.q2SliderLabel = uilabel(app.UIFigure);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.UIFigure);
            app.q2Slider.Limits = [-180 180];
            app.q2Slider.ValueChangingFcn = createCallbackFcn(app, @q2SliderValueChanging, true);
            app.q3SliderLabel = uilabel(app.UIFigure);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.UIFigure);
            app.q3Slider.Limits = [-180 180];
            app.q3Slider.ValueChangingFcn = createCallbackFcn(app, @q3SliderValueChanging, true);
            app.q4SliderLabel = uilabel(app.UIFigure);
            app.q4SliderLabel.Text = 'q4';
            app.q4Slider = uislider(app.UIFigure);
            app.q4Slider.Limits = [-180 180];
            app.q4Slider.ValueChangingFcn = createCallbackFcn(app, @q4SliderValueChanging, true);
            app.q5SliderLabel = uilabel(app.UIFigure);
            app.q5SliderLabel.Text = 'q5';
            app.q5Slider = uislider(app.UIFigure);
            app.q5Slider.Limits = [-180 180];
            app.q5Slider.ValueChangingFcn = createCallbackFcn(app, @q5SliderValueChanging, true);
            app.q6SliderLabel = uilabel(app.UIFigure);
            app.q6SliderLabel.Text = 'q6';
            app.q6Slider = uislider(app.UIFigure);
            app.q6Slider.Limits = [-180 180];
            app.q6Slider.ValueChangingFcn = createCallbackFcn(app, @q6SliderValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = teachFunctionDidi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end